classdef ksGUI_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end

    
    properties (Access = private)
        H % struct of handles to useful parts of the gui
        
        P % struct of parameters of the gui to remember, like last working directory
        
        ops % struct for kilosort to run
        
        rez % struct of results of running % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ksGUI_test

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end